classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Label                         matlab.ui.control.Label
        OKButton3                     matlab.ui.control.Button
        OKButton2                     matlab.ui.control.Button
        emailEditField                matlab.ui.control.EditField
        GHemailaddressEditFieldLabel  matlab.ui.control.Label
        usernameEditField             matlab.ui.control.EditField
        GHUsernameEditFieldLabel      matlab.ui.control.Label
        OKButton1                     matlab.ui.control.Button
        userInput                     matlab.ui.control.EditField
        TextArea                      matlab.ui.control.EditField
        LinkCodetoGithubButton        matlab.ui.control.Button
        ConnectGHButton               matlab.ui.control.Button
        CloneRepositoryButton         matlab.ui.control.Button
        DownloadsPanel                matlab.ui.container.Panel
        GHCLIButton                   matlab.ui.control.Button
        GHDesktopButton               matlab.ui.control.Button
        GitButton                     matlab.ui.control.Button
    end


    methods (Access = private)

        function InstallProgram(app, prog, download, btn)
            % Run the installer based on the operating system
            %             if ismac
            %                 %Check to see if Homebrew is install
            %                 [~,result] = system('command -v brew');
            %
            %                 if isempty(result) %if not
            %                     %Install homebrew
            %                     system('/bin/bash -c "$(curl -fsSL https://raw.githubusercontent.com/Homebrew/install/HEAD/install.sh)"');
            %                 end
            %                 if strcmp(download, 'Git')
            %                     [Installstatus, error] = system('brew install git');
            %                 elseif strcmp(download, 'Desktop')
            %                     [Installstatus, error] = system('brew install --cask github');
            %                 elseif strcmp(download,'GithubCLI.msi')
            %                    [status, ~] = system('which gh'); %check if already installed
            %                    if status == 0 %Git is installed. Update instead
            %                        [Installstatus, ~] = system('brew upgrade gh');
            %                    else
            %                        [Installstatus, error] = system('brew install gh');
            %                    end
            %                 end
            if ismac
                % Check to see if MacPorts is installed
                [~, result] = system('command -v port');

                if isempty(result) % if not
                    % Install MacPorts (assuming the user has Xcode and the command line tools installed)
                    system('curl -O https://distfiles.macports.org/MacPorts/MacPorts-2.8.1.tar.bz2');
                    system('tar xjvf MacPorts-2.8.1.tar.bz2');
                    cd('MacPorts-2.8.1');
                    system('./configure && make && sudo make install');
                    cd('..');
                    system('rm -rf MacPorts-2.8.1 MacPorts-2.8.1.tar.bz2');
                    system('sudo port -v selfupdate');
                end

                if strcmp(download, 'Git')
                    [Installstatus, error] = system('sudo port install git');
                elseif strcmp(download, 'Desktop')
                    % MacPorts does not have a 'cask' equivalent, so we'll need to install GitHub Desktop manually
                    system('curl -o GitHubDesktop.zip -L https://central.github.com/deployments/desktop/desktop/latest/darwin');
                    system('unzip GitHubDesktop.zip -d /Applications');
                    system('rm GitHubDesktop.zip');
                    Installstatus = 0; % Assuming the installation is successful
                    error = '';
                elseif strcmp(download, 'GithubCLI.msi')
                    [status, ~] = system('command -v gh'); % check if already installed
                    if status == 0 % GitHub CLI is installed. Update instead
                        [Installstatus, ~] = system('sudo port upgrade gh');
                    else
                        [Installstatus, error] = system('sudo port install gh');
                    end
                end
            elseif isunix
                system('sudo apt update'); % Update the package index

                if strcmp(download, 'Git')
                    [Installstatus, error] = system('sudo apt install git -y');
                elseif strcmp(download, 'Desktop')
                    [Installstatus, error] = system('sudo apt install github-desktop');
                elseif strcmp(download,'GithubCLI.msi')
                    [status, ~] = system('which snap');
                    if status ~= 0 %if snap is not installed
                        system('sudo apt install snapd');
                        system('sudo apt install snapd-xdg-open'); %install class snap support
                    end
                    [Installstatus, error] = system('sudo snap install gh');
                end
            elseif ispc
                % On Windows, directly run the installer
                rootSettings = matlab.internal.getSettingsRoot;
                addonFolder = rootSettings.matlab.addons.InstallationFolder.ActiveValue;
                matlabFolder = fullfile(getenv('USERPROFILE'),'AppData', 'Roaming', 'MathWorks', 'MATLAB');
                InstallerPath = fullfile(addonFolder, 'Apps','GitMatlab', 'Windows', download);
                addpath(InstallerPath)

                % Check if the MATLAB folder exists before adding to the path
                if exist(matlabFolder, 'dir')
                    addpath(matlabFolder);
                else
                    disp(['MATLAB folder not found: ', matlabFolder]);
                end
                [Installstatus, error] = system(InstallerPath);
            else
                app.TextArea.Value = 'Unsupported operating system.';
                drawnow;
            end

        app.TextArea.Value = ['Installing ', prog];
        drawnow;

        if Installstatus == 0
            app.TextArea.Value = 'Installation Complete';
            drawnow;

            btn.BackgroundColor = [0.90,0.90,0.90];
            btn.FontColor = [0.80,0.80,0.80];
        else
            app.TextArea.Value = 'Installation Failed';
            app.TextArea.Value = error;
            drawnow;
        end
    end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.UIFigure.Name = 'Git&Matlab'; %change the app title

        % Check to see if the program is installed. If it is, grey out
        % the button but leave it enable in case user wants to update
        % the program

        if ispc
            [Gstatus, ~] = system('where git');
            [GHstatus, ~] = system('where github.bat');
            [CLIstatus, ~] = system('where Github CLI');
        elseif ismac || islinux
            [Gstatus, ~] = system('which git');
            [GHstatus, ~] = system('which github.bat');
            [CLIstatus, ~] = system('which gh');
        end


        if Gstatus == 0
            app.GitButton.BackgroundColor = [0.90,0.90,0.90];
            app.GitButton.FontColor = [0.80,0.80,0.80];
        end
        if GHstatus == 0
            app.GHDesktopButton.BackgroundColor = [0.90,0.90,0.90];
            app.GHDesktopButton.FontColor = [0.80,0.80,0.80];
        end
        if CLIstatus == 0 %If it is downloaded
            app.GHCLIButton.BackgroundColor = [0.90,0.90,0.90];
            app.GHCLIButton.FontColor = [0.80,0.80,0.80];

            app.CloneRepositoryButton.Enable = 'on';
            app.ConnectGHButton.Enable = 'on';
            app.LinkCodetoGithubButton.Enable = 'on';
        else %if not downloaded
            app.Label.Visible = 'on';
        end


        end

        % Button pushed function: GitButton
        function GitButtonPushed(app, event)
        InstallProgram(app, 'Git', 'Git', app.GitButton);

        %Set up git username and email address.
        app.TextArea.Value = "Enter GitHub credentials: ";
        drawnow;

        app.emailEditField.Visible = 'on';
        app.usernameEditField.Visible = 'on';
        app.GHUsernameEditFieldLabel.Visible = 'on';
        app.GHemailaddressEditFieldLabel.Visible = 'on';
        app.OKButton2.Visible = 'on';

        end

        % Button pushed function: GHDesktopButton
        function GHDesktopButtonPushed(app, event)
        InstallProgram(app, 'Github', 'Desktop', app.GHDesktopButton);
        end

        % Button pushed function: GHCLIButton
        function GHCLIButtonPushed(app, event)
        InstallProgram(app, 'Github CLI', 'GithubCLI.msi', app.GHCLIButton);

        app.CloneRepositoryButton.Enable = 'on';
        app.ConnectGHButton.Enable = 'on';
        app.LinkCodetoGithubButton.Enable = 'on';
        app.Label.Visible = 'off';
        end

        % Button pushed function: ConnectGHButton
        function ConnectGHButtonPushed(app, event)
        % Run the system command
        app.TextArea.Value = "Enter code from Command Window into browser pop-up";
        drawnow;
        web('https://github.com/login/device');
        system('gh auth login');
        app.TextArea.Value = "";
        drawnow;

        end

        % Button pushed function: LinkCodetoGithubButton
        function LinkCodetoGithubButtonPushed(app, event)
        app.TextArea.Value = "Name for the repository: ";
        drawnow;
        app.userInput.Visible = 'on';
        app.OKButton1.Visible = 'on';
        app.OKButton1.Enable = 'on';
        end

        % Button pushed function: OKButton1
        function OKButton1Pushed(app, event)
        %Repository OK button

        repoName = app.userInput.Value;


        % Check if the repository already exists
        [status, result] = system(['gh repo view ' repoName]);

        if status == 0
            % Repository already exists
            answer = questdlg('Repository already exists. Do you want to overwrite?', ...
                'Repository Exists', 'Yes', 'No', 'No');
            if strcmp(answer, 'No')
                app.TextArea.Value = 'Aborted. Files not pushed.';
                app.userInput.Visible = 'off';
                app.OKButton1.Visible = 'off';
                app.userInput.Value = '';
                return;
            end
            % Extract the username and repository name from the result
            result = regexp(result, 'name:\s*(\S+)', 'tokens', 'once');

            % Construct the URL of the repository
            repoUrl = ['https://github.com/' result{1}];

            % Display the URL or use it as needed
            disp(['Repository URL: ' repoUrl]);

            app.TextArea.Value = 'Overwriting existing repository...';
            drawnow;

            % Add contents to local git repo
            !git init
            !git add .
            !git commit -m "Initial commit"

            % Push files to the existing repository
            system(['git push -u ' repoUrl]);

            app.TextArea.Value = 'Files pushed';
            drawnow;
        else
            % Repository doesn't exist, create a new one
            [status, result] = system(['gh repo create ' repoName ' --public']);

            app.OKButton1.Enable = 'off';
            app.TextArea.Value = 'Creating repository...';
            drawnow;

            % Add contents to local git repo
            !git init
            !git add .
            !git commit -m "Initial commit"

            if status == 0
                % Add the created repository as a remote
                command = ['git remote add ' repoName ' ' result];
                system(command);

                % Push files to the newly created repository
                [status, error] = system(['git push -u ' result]);
                if status == 0
                    app.TextArea.Value = 'Repository Created';
                    drawnow;
                else
                    app.TextArea.Value = ['Error: ' error];
                    drawnow;
                end

            else
                app.TextArea.Value = 'Failed to create repository';
                drawnow;
            end
        end

        app.userInput.Visible = 'off';
        app.OKButton1.Visible = 'off';
        app.userInput.Value = '';

        %             [status, result] = system(['gh repo create ' repoName ' --public']);
        %
        %             if status == 0
        %                 %system(['gh remote add repoName ' result])
        %                 command = ['git remote add ' repoName ' ' result];
        %                 system(command);
        %
        %                 command = ['git push --set-upstream ' repoName ' master'];
        %                 system(command);
        %
        %                 app.TextArea.Value = "Repository Created";
        %                 drawnow;
        %             end


        end

        % Button pushed function: CloneRepositoryButton
        function CloneRepositoryButtonPushed(app, event)
        app.TextArea.Value = "Enter repository URL: ";
        drawnow;

        app.userInput.Visible = 'on';
        app.OKButton3.Visible = 'on';
        end

        % Button pushed function: OKButton2
        function OKButton2Pushed(app, event)
        %OK button for installing Git

        user = app.usernameEditField.Value;
        email = app.emailEditField.Value;

        command = ['git config --global user.name ' user];
        system(command);
        command = ['git config --global user.email ' email];
        system(command);

        app.TextArea.Value = 'GitHub credentials are set up.';
        drawnow;

        app.emailEditField.Visible = 'off';
        app.usernameEditField.Visible = 'off';
        app.OKButton2.Visible = 'off';
        app.GHUsernameEditFieldLabel.Visible = 'off';
        app.GHemailaddressEditFieldLabel.Visible = 'off';
        pause(5);
        app.TextArea.Value = ' ';
        drawnow;
        end

        % Button pushed function: OKButton3
        function OKButton3Pushed(app, event)
        %Button to Confirm Clone

        URL = app.userInput.Value;

        command = ['git clone ' URL];
        [status, ~] = system(command);

        if status == 0
            app.TextArea.Value = 'Cloning Complete';
            drawnow

            app.OKButton3.Visible = 'off';
            app.userInput.Visible = 'off';
            app.userInput.Value = '';
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DownloadsPanel = uipanel(app.UIFigure);
            app.DownloadsPanel.Tooltip = {'Available programs to download. If greyed, program is already installed.'};
            app.DownloadsPanel.Title = 'Downloads';
            app.GitButton = uibutton(app.DownloadsPanel, 'push');
            app.GitButton.ButtonPushedFcn = createCallbackFcn(app, @GitButtonPushed, true);
            app.GitButton.Text = 'Git';
            app.GHDesktopButton = uibutton(app.DownloadsPanel, 'push');
            app.GHDesktopButton.ButtonPushedFcn = createCallbackFcn(app, @GHDesktopButtonPushed, true);
            app.GHDesktopButton.Text = 'Github Desktop';
            app.GHCLIButton = uibutton(app.DownloadsPanel, 'push');
            app.GHCLIButton.ButtonPushedFcn = createCallbackFcn(app, @GHCLIButtonPushed, true);
            app.GHCLIButton.Text = 'Github CLI';
            app.CloneRepositoryButton = uibutton(app.UIFigure, 'push');
            app.CloneRepositoryButton.ButtonPushedFcn = createCallbackFcn(app, @CloneRepositoryButtonPushed, true);
            app.CloneRepositoryButton.Enable = 'off';
            app.CloneRepositoryButton.Text = 'Clone Repository';
            app.ConnectGHButton = uibutton(app.UIFigure, 'push');
            app.ConnectGHButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectGHButtonPushed, true);
            app.ConnectGHButton.Enable = 'off';
            app.ConnectGHButton.Text = 'Connect to Github';
            app.LinkCodetoGithubButton = uibutton(app.UIFigure, 'push');
            app.LinkCodetoGithubButton.ButtonPushedFcn = createCallbackFcn(app, @LinkCodetoGithubButtonPushed, true);
            app.LinkCodetoGithubButton.Enable = 'off';
            app.LinkCodetoGithubButton.Text = 'Link Code to Github';
            app.TextArea = uieditfield(app.UIFigure, 'text');
            app.TextArea.Editable = 'off';
            app.userInput = uieditfield(app.UIFigure, 'text');
            app.userInput.Visible = 'off';
            app.OKButton1 = uibutton(app.UIFigure, 'push');
            app.OKButton1.ButtonPushedFcn = createCallbackFcn(app, @OKButton1Pushed, true);
            app.OKButton1.Visible = 'off';
            app.OKButton1.Text = 'OK';
            app.GHUsernameEditFieldLabel = uilabel(app.UIFigure);
            app.GHUsernameEditFieldLabel.Visible = 'off';
            app.GHUsernameEditFieldLabel.Text = 'GH Username:';
            app.usernameEditField = uieditfield(app.UIFigure, 'text');
            app.usernameEditField.Visible = 'off';
            app.GHemailaddressEditFieldLabel = uilabel(app.UIFigure);
            app.GHemailaddressEditFieldLabel.Visible = 'off';
            app.GHemailaddressEditFieldLabel.Text = 'GH email address:';
            app.emailEditField = uieditfield(app.UIFigure, 'text');
            app.emailEditField.Visible = 'off';
            app.OKButton2 = uibutton(app.UIFigure, 'push');
            app.OKButton2.ButtonPushedFcn = createCallbackFcn(app, @OKButton2Pushed, true);
            app.OKButton2.Visible = 'off';
            app.OKButton2.Text = 'OK';
            app.OKButton3 = uibutton(app.UIFigure, 'push');
            app.OKButton3.ButtonPushedFcn = createCallbackFcn(app, @OKButton3Pushed, true);
            app.OKButton3.Visible = 'off';
            app.OKButton3.Text = 'OK';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.Label.Text = 'Need to Download CLI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end